classdef AESapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ECBdecryptionButton   matlab.ui.control.Button
        ECBencryptionButton   matlab.ui.control.Button
        CBCdecryptionButton   matlab.ui.control.Button
        CBCencryptionButton   matlab.ui.control.Button
        KeyAdditionButton     matlab.ui.control.Button
        MixColumnsButton      matlab.ui.control.Button
        ShiftRowsButton       matlab.ui.control.Button
        SBoxButton            matlab.ui.control.Button
        ResetButton           matlab.ui.control.Button
        OutputEditField       matlab.ui.control.EditField
        OutputEditFieldLabel  matlab.ui.control.Label
        IVEditField           matlab.ui.control.EditField
        IVEditFieldLabel      matlab.ui.control.Label
        KeyEditField          matlab.ui.control.EditField
        KeyEditFieldLabel     matlab.ui.control.Label
        InputEditField        matlab.ui.control.EditField
        InputEditFieldLabel   matlab.ui.control.Label
        EnterButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        buttonPushed; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             % Make current instance of app invisible
    app.UIFigure.Visible = 'off';
    % Open 2nd instance of app
    AESapp();  
    close(app.UIFigure) 
        end

        % Button pushed function: SBoxButton
        function SBoxButtonPushed(app, event)
            app.InputEditField.Visible='on';
                        app.InputEditFieldLabel.Visible='on';

            app.buttonPushed='SBox';
            
        end

        % Button pushed function: ShiftRowsButton
        function ShiftRowsButtonPushed(app, event)
            app.InputEditField.Visible='on';
                        app.InputEditFieldLabel.Visible='on';

            app.buttonPushed='ShiftRows';
        end

        % Button pushed function: MixColumnsButton
        function MixColumnsButtonPushed(app, event)
            app.InputEditField.Visible='on';
            app.buttonPushed='MixColumns';
            app.InputEditFieldLabel.Visible='on';

        end

        % Button pushed function: KeyAdditionButton
        function KeyAdditionButtonPushed(app, event)
            app.InputEditField.Visible='on';
            app.KeyEditField.Visible='on';
            app.KeyEditFieldLabel.Visible='on';
            app.InputEditFieldLabel.Visible='on';
            app.buttonPushed='KeyAddition';
        end

        % Button pushed function: CBCencryptionButton
        function CBCencryptionButtonPushed(app, event)
            app.InputEditField.Visible='on';
            app.KeyEditField.Visible='on';
            app.IVEditField.Visible='on';
            app.KeyEditFieldLabel.Visible='on';
            app.InputEditFieldLabel.Visible='on';
            app.IVEditFieldLabel.Visible='on';
            app.buttonPushed='CBCencryption';
        end

        % Button pushed function: CBCdecryptionButton
        function CBCdecryptionButtonPushed(app, event)
            app.InputEditField.Visible='on';
            app.KeyEditFieldLabel.Visible='on';
            app.InputEditFieldLabel.Visible='on';
            app.KeyEditField.Visible='on';
            app.IVEditField.Visible='on';
                        app.IVEditFieldLabel.Visible='on';
            app.buttonPushed='CBCdecryption';
        end

        % Button pushed function: ECBencryptionButton
        function ECBencryptionButtonPushed(app, event)
            app.InputEditField.Visible='on';
            app.InputEditFieldLabel.Visible='on';
            app.KeyEditField.Visible='on';
            app.KeyEditFieldLabel.Visible='on';
            app.buttonPushed='ECBencryption';
        end

        % Button pushed function: ECBdecryptionButton
        function ECBdecryptionButtonPushed(app, event)
            app.InputEditField.Visible='on';
            
            app.KeyEditFieldLabel.Visible='on';
            app.InputEditFieldLabel.Visible='on';
            app.KeyEditField.Visible='on';
            app.buttonPushed='ECBdecryption';
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            app.InputEditField.Editable='off';
            app.KeyEditField.Editable='off';
            app.IVEditField.Editable='off';
            if isequal(app.buttonPushed,'SBox')
                input=inputHex2DecFormatted(char(app.InputEditField.Value));
                output=subBytes(input);
                app.OutputEditField.Value=outputDec2HexFormatted(output);
            elseif isequal(app.buttonPushed,'ShiftRows')
                   input=inputHex2DecFormatted(char(app.InputEditField.Value));
                   output=shiftRows(reshape(input,4,4));
                   app.OutputEditField.Value=outputDec2HexFormatted(output);
            elseif isequal(app.buttonPushed,'MixColumns')
                   input=inputHex2DecFormatted(char(app.InputEditField.Value));
                   output=mixColumns(reshape(input,4,4));
                   app.OutputEditField.Value=outputDec2HexFormatted(output);
            elseif isequal(app.buttonPushed,'KeyAddition')
                   input=inputHex2DecFormatted(char(app.InputEditField.Value));
                   key=inputHex2DecFormatted(char(app.KeyEditField.Value));
                   output=add_round_key(input,key);
                   app.OutputEditField.Value=outputDec2HexFormatted(output);
            elseif isequal(app.buttonPushed,'CBCencryption')
                   input=inputHex2DecFormatted(char(app.InputEditField.Value));
                   key=inputHex2DecFormatted(char(app.KeyEditField.Value));
                   IV=inputHex2DecFormatted(char(app.IVEditField.Value));
                   output=encryptCBC(input,key,IV);
                   app.OutputEditField.Value=outputDec2HexFormatted(output);
            elseif isequal(app.buttonPushed,'CBCdecryption')
                   input=inputHex2DecFormatted(char(app.InputEditField.Value));
                   key=inputHex2DecFormatted(char(app.KeyEditField.Value));
                   IV=inputHex2DecFormatted(char(app.IVEditField.Value));
                   output=decryptCBC(input,key,IV);
                   app.OutputEditField.Value=outputDec2HexFormatted(output);
            elseif isequal(app.buttonPushed,'ECBencryption')
                   input=inputHex2DecFormatted(char(app.InputEditField.Value));
                   key=inputHex2DecFormatted(char(app.KeyEditField.Value));
                   output=encryptECB(input,key);
                   app.OutputEditField.Value=outputDec2HexFormatted(output);
            elseif isequal(app.buttonPushed,'ECBdecryption')
                   input=inputHex2DecFormatted(char(app.InputEditField.Value));
                   key=inputHex2DecFormatted(char(app.KeyEditField.Value));
                   output=decryptECB(input,key);
                   app.OutputEditField.Value=outputDec2HexFormatted(output);
            else
            end
            app.OutputEditField.Visible='on';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.InputEditFieldLabel = uilabel(app.UIFigure);
            app.InputEditFieldLabel.Visible = 'off';
            app.InputEditFieldLabel.Text = 'Input';
            app.InputEditField = uieditfield(app.UIFigure, 'text');
            app.InputEditField.Visible = 'off';
            app.KeyEditFieldLabel = uilabel(app.UIFigure);
            app.KeyEditFieldLabel.Visible = 'off';
            app.KeyEditFieldLabel.Text = 'Key';
            app.KeyEditField = uieditfield(app.UIFigure, 'text');
            app.KeyEditField.Visible = 'off';
            app.IVEditFieldLabel = uilabel(app.UIFigure);
            app.IVEditFieldLabel.Visible = 'off';
            app.IVEditFieldLabel.Text = 'IV';
            app.IVEditField = uieditfield(app.UIFigure, 'text');
            app.IVEditField.Visible = 'off';
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Visible = 'off';
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.UIFigure, 'text');
            app.OutputEditField.Editable = 'off';
            app.OutputEditField.Visible = 'off';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SBoxButton = uibutton(app.UIFigure, 'push');
            app.SBoxButton.ButtonPushedFcn = createCallbackFcn(app, @SBoxButtonPushed, true);
            app.SBoxButton.Text = 'S-Box';
            app.ShiftRowsButton = uibutton(app.UIFigure, 'push');
            app.ShiftRowsButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftRowsButtonPushed, true);
            app.ShiftRowsButton.Text = 'Shift Rows';
            app.MixColumnsButton = uibutton(app.UIFigure, 'push');
            app.MixColumnsButton.ButtonPushedFcn = createCallbackFcn(app, @MixColumnsButtonPushed, true);
            app.MixColumnsButton.Text = 'Mix Columns';
            app.KeyAdditionButton = uibutton(app.UIFigure, 'push');
            app.KeyAdditionButton.ButtonPushedFcn = createCallbackFcn(app, @KeyAdditionButtonPushed, true);
            app.KeyAdditionButton.Text = 'Key Addition';
            app.CBCencryptionButton = uibutton(app.UIFigure, 'push');
            app.CBCencryptionButton.ButtonPushedFcn = createCallbackFcn(app, @CBCencryptionButtonPushed, true);
            app.CBCencryptionButton.Text = 'CBC encryption';
            app.CBCdecryptionButton = uibutton(app.UIFigure, 'push');
            app.CBCdecryptionButton.ButtonPushedFcn = createCallbackFcn(app, @CBCdecryptionButtonPushed, true);
            app.CBCdecryptionButton.Text = 'CBC decryption';
            app.ECBencryptionButton = uibutton(app.UIFigure, 'push');
            app.ECBencryptionButton.ButtonPushedFcn = createCallbackFcn(app, @ECBencryptionButtonPushed, true);
            app.ECBencryptionButton.Text = 'ECB encryption';
            app.ECBdecryptionButton = uibutton(app.UIFigure, 'push');
            app.ECBdecryptionButton.ButtonPushedFcn = createCallbackFcn(app, @ECBdecryptionButtonPushed, true);
            app.ECBdecryptionButton.Text = 'ECB decryption';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AESapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end